classdef winCredential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Grid1          matlab.ui.container.GridLayout
        Button         matlab.ui.control.Button
        Panel          matlab.ui.container.Panel
        Grid2          matlab.ui.container.GridLayout
        Password       matlab.ui.control.HTML
        PasswordLabel  matlab.ui.control.Label
        Login          matlab.ui.control.EditField
        LoginLabel     matlab.ui.control.Label
        Subtitle       matlab.ui.control.Label
        Title          matlab.ui.control.Label
        Image          matlab.ui.control.Image
    end

    
    properties (Access = private)
        
        CallingApp
        
    end
    
    methods (Access = private)
        
        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'Main', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'Main', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'Main', 'Interpreter', 'html', 'Icon', 'info_LT.png')
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app, mainapp)
            
            app.CallingApp = mainapp;
            
            app.Password.HTMLSource = sprintf('<!DOCTYPE html>\n<html>\n<head>\n\t<script type="text/javascript">\n\t\tfunction setup(htmlComponent) {\n\t\t\tvar dom = document.getElementById("pwrd");\n\t\t\twindow.updateData = function (newData) {\n\t\t\t\thtmlComponent.Data = dom.value;\n\t\t\t}\n\t\t}\n\t</script>\n</head>\n<body>\n\t<input type="password" id="pwrd" onchange="updateData()" style="padding-left: 3px; width: 384px; height: 16px"></input>\n</body>\n</html>');
            
            Position = app.CallingApp.UIFigure.Position;
            x0 = fix(Position(1)+(Position(3)-app.UIFigure.Position(3))/2);
            y0 = fix(Position(2)+(Position(4)-app.UIFigure.Position(4))/2);
            app.UIFigure.Position(1:2) = [x0, y0];
            
        end

        % Close request function: UIFigure
        function closeRequest(app, event)

            delete(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if (app.Login.Value == "admin") & (string(app.Password.Data) == "123456")
                d = uiprogressdlg(app.UIFigure, "Message", "Good job! Closing this window in 2 seconds...", "Interpreter", "html");
                while true
                    d.Value = d.Value + .01;
                    pause(.02)

                    if abs(d.Value - .99) < 1e-3
                        break
                    end
                end

                closeRequest(app)
                                    
            else
                MessageBox(app, 'info', 'Try again! The credentials are "admin" and "123456", ok?')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Main';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.Grid1);
            app.Image.ImageSource = 'settings_LT.png';
            app.Title = uilabel(app.Grid1);
            app.Title.Interpreter = 'html';
            app.Title.Text = {'<b style="color: #262626; font-size: 24;">General Settings</b>'; '<font>Lorem ipsum dolor sit sed do eiusmod tempor  </font>'; ' '};
            app.Subtitle = uilabel(app.Grid1);
            app.Subtitle.Text = 'Credentials:';
            app.Panel = uipanel(app.Grid1);
            app.Grid2 = uigridlayout(app.Panel);
            app.LoginLabel = uilabel(app.Grid2);
            app.LoginLabel.Text = 'User:';
            app.Login = uieditfield(app.Grid2, 'text');
            app.PasswordLabel = uilabel(app.Grid2);
            app.PasswordLabel.Text = 'Login:';
            app.Password = uihtml(app.Grid2);
            app.Button = uibutton(app.Grid1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winCredential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end