classdef winDriveTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        mainGrid             matlab.ui.container.GridLayout
        Panel                matlab.ui.container.Panel
        imgShowHide          matlab.ui.control.Image
        mainPanel            matlab.ui.container.Panel
        mainPanel_Grid       matlab.ui.container.GridLayout
        Tab2_Panel           matlab.ui.container.GridLayout
        binTable_Grid        matlab.ui.container.GridLayout
        binTable_Col4        matlab.ui.control.Label
        binTable_Col3        matlab.ui.control.Label
        binTable_Col2        matlab.ui.control.Label
        binTable_Col1        matlab.ui.control.Label
        binTable_UITable     matlab.ui.control.Table
        Tab2_Grid            matlab.ui.container.GridLayout
        Tab2_Image           matlab.ui.control.Image
        Tab2_Title           matlab.ui.control.Label
        Tab1_Panel           matlab.ui.container.GridLayout
        GridLayout           matlab.ui.container.GridLayout
        imgDensity           matlab.ui.control.Image
        imgScatter           matlab.ui.control.Image
        imgRoute             matlab.ui.control.Image
        EmissionsLabel       matlab.ui.control.Label
        FilterTreeInfo       matlab.ui.control.Label
        FilterAction_Grid2   matlab.ui.container.GridLayout
        FilterAction_del     matlab.ui.control.Image
        FilterAction_Grid1   matlab.ui.container.GridLayout
        FilterAction_add     matlab.ui.control.Image
        FilterTree2          matlab.ui.container.Tree
        FilterTree1          matlab.ui.container.Tree
        levelFilterNode      matlab.ui.container.TreeNode
        levelFilter_thr      matlab.ui.container.TreeNode
        geoFilterNode        matlab.ui.container.TreeNode
        geoFilter_Circle     matlab.ui.container.TreeNode
        geoFilter_Rectangle  matlab.ui.container.TreeNode
        geoFilter_Polygon    matlab.ui.container.TreeNode
        FilterTreeLabel      matlab.ui.control.Label
        rawDataInfo          matlab.ui.control.TextArea
        Emissions            matlab.ui.control.DropDown
        Tab1_Grid            matlab.ui.container.GridLayout
        Tab1_Image           matlab.ui.control.Image
        Tab1_Title           matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        specData
        Index
        
        specTable                                                           % General table (instantaneous channel power)
        filtTable                                                           % Filtered specData

        roiFilters = table('Size', [0, 4],                                      ...
                          'VariableTypes', {'cell', 'cell', 'cell', 'double'}, ...
                          'VariableNames', {'type', 'subtype', 'handle', 'enable'})
        
        UIAxes1
        UIAxes2

        % app.UIAxes1        
        hRoute_inROI
        hRoute_outROI
        hScatter
        hDensity

        % app.UIAxes2
        hChannelPower
        hSmoothedData
        hROI_Line
        hROI_Label        
        
    end

    
    methods (Access = private)

        function Fcn1_AxesCreation(app)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "In progress...", "Interpreter", "html");

            % Axes layout
            t = tiledlayout(app.Panel, 5, 1, "Padding", "tight");
            
            app.UIAxes2 = axes(t);
            app.UIAxes2.Layout.Tile = 5;
            
            app.UIAxes1 = geoaxes(t, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');
            app.UIAxes1.Layout.Tile = 1;
            app.UIAxes1.Layout.TileSpan = [4 1];


            % app.UIAxes2
            xlabel(app.UIAxes2, 'Sample')
            ylabel(app.UIAxes2, 'Channel power (dBm)')
            
            set(app.UIAxes2, Color = [0 0 0], ...
                             ClippingStyle = 'rectangle', ...
                             XGrid = 'on', ...
                             XMinorGrid = 'on', ...
                             YGrid = 'on', ...
                             YMinorGrid = 'on', ...
                             FontSize = 6, ...
                             GridColor = [0.9412 0.9412 0.9412])

            app.UIAxes2.Interactions = [panInteraction, zoomInteraction, rulerPanInteraction];
            axtoolbar(app.UIAxes2, {'restoreview'});


            % app.UIAxes1
            while true
                try
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    app.UIAxes1.TitleHorizontalAlignment = 'right';
                    set(app.UIAxes1.Title, 'String', 'Title', 'FontSize', 8)
                    set(app.UIAxes1.Subtitle, 'String', 'Subtitle', 'FontSize', 6.5)

                    colormap(app.UIAxes1, 'jet')
                    hold(app.UIAxes1, 'on')

                    break
                catch
                end
            end

            try
                geobasemap(app.UIAxes1, 'streets-light')
            catch
            end

            drawnow
            delete(d)

        end
        

        function Fcn2_General(app)   

            Fcn2_General_Emissions(app)
            Fcn2_General_MetaData(app)
            Fcn2_General_AxesTitle(app)
            Fcn2_General_specTable(app)

            expand(app.FilterTree1, 'all')
        
        end


        function Fcn2_General_Emissions(app)

            emissionTable = app.specData.Emissions;
            
            app.Emissions.Items = {};
            for ii = 1:height(emissionTable)
                app.Emissions.Items{end+1} = sprintf('%d: %.3f MHz @ %.3f kHz',        ...
                                                          ii,                          ...
                                                          emissionTable.Frequency(ii), ...
                                                          emissionTable.BW(ii));
            end
            app.Emissions.Value = app.Emissions.Items{app.Index(2)};

        end


        function Fcn2_General_MetaData(app)

            metaData = '';
            for ii = 1:height(app.specData.RelatedFiles)
                ObservationTime = sprintf('%s - %s', datestr(app.specData.RelatedFiles.BeginTime(ii), 'dd/mm/yyyy HH:MM:SS'), ...
                                                     datestr(app.specData.RelatedFiles.EndTime(ii),   'dd/mm/yyyy HH:MM:SS'));

                metaData = sprintf(['%s• %s\n' ...
                                    '  %s\n' ...
                                    '  %.0f samples, %.3f sec (mean revisit time)\n\n'], ...
                                    metaData, ...
                                    app.specData.RelatedFiles.Name{ii}, ...
                                    ObservationTime, ...
                                    app.specData.RelatedFiles.Samples(ii), ...
                                    app.specData.RelatedFiles.RevisitTime(ii));
            end

            app.rawDataInfo.Value = sprintf(['Band: %.3f - %.3f MHz\n\n'  ...
                                      'Data points for sample: %.0f\n'    ...
                                      'Unit: %s\n'               ...
                                      'Resolution: %.3f kHz\n\n%s'], ...
                                      app.specData.MetaData.FreqStart / 1e+6,  ...
                                      app.specData.MetaData.FreqStop  / 1e+6,  ...
                                      app.specData.MetaData.DataPoints,        ...
                                      app.specData.MetaData.metaString{1},     ...
                                      app.specData.MetaData.Resolution / 1e+3, ...
                                      metaData);

        end


        function Fcn2_General_AxesTitle(app)

            app.UIAxes1.Title.String    = extractAfter(app.Emissions.Value, ': ');
            app.UIAxes1.Subtitle.String = sprintf('%s - %s', datestr(min(app.specData.RelatedFiles.BeginTime), 'dd/mm/yyyy HH:MM:SS'), ...
                                                             datestr(max(app.specData.RelatedFiles.EndTime),   'dd/mm/yyyy HH:MM:SS'));

        end
       

        function Fcn2_General_specTable(app)

            timestamp = app.specData.Data{1}';
            
            Lat  = [];
            Long = [];
            for ii = 1:numel(app.specData.RelatedGPS)
                Lat  = [Lat;  app.specData.RelatedGPS{ii}.Matrix(:,1)];
                Long = [Long; app.specData.RelatedGPS{ii}.Matrix(:,2)];
            end
            
            chPower = Fcn4_chPower(app);
            app.specTable = table(timestamp, Lat, Long, chPower);

        end


        function Fcn2_General_TreeBuilding(app)

            if ~isempty(app.FilterTree2.Children)
                delete(app.FilterTree2.Children)
            end

            if ~isempty(app.roiFilters)
                for ii = 1:height(app.roiFilters)
                    uitreenode(app.FilterTree2, 'Text', app.roiFilters.subtype{ii}, 'NodeData', ii);
                end

                set(app.FilterTree2, 'SelectedNodes', app.FilterTree2.Children(end), 'Enable', 1)
                FilterTree2_SelectionChanged(app)

                app.FilterAction_del.Enable = 1;
            else
                app.FilterTree2.Enable = 1;
                app.FilterAction_del.Enable = 0;
            end

            if isempty(find(app.roiFilters.subtype == "THRESHOLD", 1))
                s = uistyle("FontColor", [0,0,0]);                    
                app.levelFilter_thr.NodeData = 1;
            else
                s = uistyle("FontColor", [.8,.8,.8]);
                app.levelFilter_thr.NodeData = [];
            end
            addStyle(app.FilterTree1, s, 'node', app.levelFilter_thr)

        end


        function Fcn3_updateInfo(app)

            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "In progress...", "Interpreter", "html");

            Fcn4_filtTable(app)
            Fcn5_Table(app)
            Fcn6_Plots(app)

            delete(d)

        end
        

        function Fcn4_filtTable(app)

            idy  = ones(height(app.specTable), 1, 'logical');

            idx1 = find(app.roiFilters.type == "Level");
            idx2 = find(app.roiFilters.type == "Geographic ROI");

            if ~isempty(idx1) & ~isempty(idx2)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.roiFilters.handle{ii}.Position(3);
                end

                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.roiFilters.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = and(idy1, idy2);

            elseif ~isempty(idx1)
                for ii = idx1'
                    idy1 = app.specTable.chPower >= app.roiFilters.handle{ii}.Position(3);
                end

                idy = idy1;

            elseif ~isempty(idx2)
                idy2 = zeros(height(app.specTable), 1, 'logical');
                for ii = idx2'
                    idy2 = or(idy2, inROI(app.roiFilters.handle{ii}, app.specTable.Lat, app.specTable.Long));
                end

                idy = idy2;                
            end
            
            if ~sum(idy)
                MessageBox(app, 'warning', 'Invalid filter!')
                app.FilterTree2.SelectedNodes = app.FilterTree2.Children(end);
                FilterAction_delCallback(app)
                return
            end

            app.specTable.filtered = idy;
            app.specTable.PK1      = int32(1:height(app.specTable))';

            app.filtTable = app.specTable(idy,2:4);
            app.filtTable.FK1 = find(idy);

            app.FilterTreeInfo.Text = sprintf('%.0f%%', 100*sum(idy)/height(app.specTable));
            if ~isempty(idx1)
                app.roiFilters.handle{idx1}.Position(2) = height(app.filtTable);
            end

        end


        function chPower = Fcn4_chPower(app)

            idx = find(strcmp(app.Emissions.Items, app.Emissions.Value), 1);
            FreqCenter = app.specData.Emissions.Frequency(idx) * 1e+6;
            BW         = app.specData.Emissions.BW(idx)        * 1e+3;

            chPower = Misc_chPower(app.specData, [FreqCenter-BW/2, FreqCenter+BW/2]);

        end


        function Fcn5_Table(app)

            app.binTable_UITable.Data = app.filtTable(:,[4,1:3]);

        end


        function Fcn6_Plots(app)

            Fcn6_Plot_chPower(app)
            Fcn6_Plot_Route(app)
            Fcn6_Plot_Scatter(app)
            Fcn6_Plot_Density(app)
            drawnow

        end


        function Fcn6_Plot_chPower(app)

            idx = 1;
            try
                if app.hROI_Line.Position(1) <= height(app.filtTable)
                    idx = round(app.hROI_Line.Position(1));
                end
            catch
            end

            colors = [1.00, 0.07, 0.65; ...
                      0.47, 0.67, 0.19; ...
                      1.00, 0.07, 0.65; ...
                      1.00, 1.00, 0.07];
            
            h = findobj(app.UIAxes2.Children, '-not', 'Tag', 'FilterLevel_THRESHOLD');
            delete(h)

            hold(app.UIAxes2, 'on')
            app.UIAxes2.XAxis.Exponent = 0;

            [minY, maxY] = bounds(app.filtTable.chPower);
            set(app.UIAxes2, 'XLim', [1, height(app.filtTable)], 'YLim', [minY, maxY])
                                
            plot(app.UIAxes2, app.filtTable.chPower, 'Tag', 'rawTable', 'Color', colors(4,:));

            app.hSmoothedData = plot(app.UIAxes2, smoothdata(app.filtTable.chPower, 'movmean', 'SmoothingFactor', .5), 'Color', colors(3,:));
            app.hROI_Line     = images.roi.Line(app.UIAxes2, 'Position', [idx minY; idx maxY], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate');
            app.hROI_Label    = text(app.UIAxes2, idx, app.UIAxes2.YLim(1)+.94*diff(app.UIAxes2.YLim), sprintf('  %.0f dBm', app.filtTable.chPower(idx)), 'FontSize', 8, 'FontWeight', 'bold', 'Color', 'red', 'FontSmoothing', 'on');
                                
            DataName = {'Filtered data', 'Smoothed data'};

            addlistener(app.hROI_Line, 'MovingROI', @app.timeROICallback);
            addlistener(app.hROI_Line, 'ROIMoved',  @app.timeROICallback);

            legend(app.UIAxes2, DataName)
            set(app.UIAxes2.Legend, FontSize=6, Box='on', Color=[.98,.98,.98], EdgeColor=[.98,.98,.98])
            hold(app.UIAxes2, 'off')

        end


        function Fcn6_Plot_Route(app)

            delete([app.hRoute_outROI, app.hRoute_inROI])

            outROITable = app.specTable(~app.specTable.filtered,:);
            inROITable  = app.filtTable;

            if app.imgRoute.ImageSource == "DriveTest_Route_ON.png"; Visibility = 1;
            else;                                                    Visibility = 0;
            end

            app.hRoute_outROI = geoscatter(app.UIAxes1, outROITable.Lat, outROITable.Long, 'filled', 'Marker', '.', 'MarkerFaceColor', "#808080", 'MarkerEdgeColor', "#808080", 'SizeData', 50, 'Visible', Visibility);
            app.hRoute_inROI  = geoplot(app.UIAxes1,    inROITable.Lat, inROITable.Long, 'Color', "#de8a8a", 'LineWidth', .5, 'Visible', Visibility);

        end
        

        function Fcn6_Plot_Scatter(app)

            delete(app.hScatter)

            tempTable = app.filtTable;

            if app.imgScatter.ImageSource == "DriveTest_Scatter_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hScatter = geoscatter(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, [], tempTable{:,3}, 'filled', ...
                                      'SizeData', 50, 'Visible', Visibility);

        end


        function Fcn6_Plot_Density(app)

            delete(app.hDensity)
            drawnow

            tempTable = app.filtTable;

            weights = tempTable{:,3};
            if min(weights) < 0
                weights = weights+abs(min(weights));
            end

            if app.imgDensity.ImageSource == "DriveTest_Density_ON.png"; Visibility = 1;
            else;                                                        Visibility = 0;
            end

            app.hDensity = geodensityplot(app.UIAxes1, tempTable{:,1}, tempTable{:,2}, weights, ...
                                          'FaceColor','interp', 'Radius', 1000, 'Visible', Visibility);

        end


        function Fcn7_Plot_StackOrder(app, objOnTop)

            if objOnTop.Tag ~= "FilterLevel_THRESHOLD"; ax = app.UIAxes1;
            else;                                       ax = app.UIAxes2;
            end

            idx = [];
            for ii = 1:numel(ax.Children)
                if isequal(ax.Children(ii), objOnTop)
                    idx = ii;
                    break
                end
            end
            
            if ~isempty(idx)
                ax.Children = ax.Children([idx, setdiff(1:numel(ax.Children), idx)]);
            end

        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'DriveTest', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'DriveTest', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'DriveTest', 'Interpreter', 'html', 'Icon', 'LT_info.png')
                case 'close';   uialert(app.UIFigure, msg, 'DriveTest', 'Interpreter', 'html', 'CloseFcn', @(~,~)closeFcn(app));
            end

        end


        function filterROICallback(app, src, event)

            switch(event.EventName)
                case 'MovingROI'
                    disableDefaultInteractivity(app.UIAxes1)
                    disableDefaultInteractivity(app.UIAxes2)
                    
                case 'ROIMoved'
                    enableDefaultInteractivity(app.UIAxes1)
                    enableDefaultInteractivity(app.UIAxes2)
                    
                    Fcn3_updateInfo(app)
                    Fcn7_Plot_StackOrder(app, event.Source)
            end
            
        end
        
        
        function timeROICallback(app, src, event)
            
            idx = round(event.Source.Position(1));

            switch(event.EventName)
                case 'MovingROI'
                    disableDefaultInteractivity(app.UIAxes2)

                    try
                        app.hROI_Label.Position(1) = idx;
                        app.hROI_Label.String      = sprintf('  %.0f dBm', app.filtTable.chPower(idx));
                    catch
                        enableDefaultInteractivity(app.UIAxes2)
                    end
                    
                case 'ROIMoved'
                    enableDefaultInteractivity(app.UIAxes2)

                    [minY, maxY] = bounds(app.filtTable.chPower);
                    app.hROI_Line.Position = [idx minY; idx maxY];
            end
            
        end

    end
    
    
    methods (Access = public)

        function timeROICallback_auxFcn(app, timeIndex)

            idx = find(app.filtTable.FK1 == timeIndex, 1);

            if ~isempty(idx)
                app.hROI_Line.Position(1:2) = idx;
                app.hROI_Label.Position(1)  = idx;
                app.hROI_Label.String       = sprintf('  %.0f dBm', app.filtTable.chPower(idx));
            end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            mainMonitor = get(0, 'MonitorPositions');
            
            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            PanelTab_SelectionChanged(app, struct('Source', app.Tab1_Image))
            
            load("DriveTestDB.mat", "-mat", "rawData")

            app.specData = rawData;
            app.Index    = [1, 1];

            Fcn1_AxesCreation(app)                        
            Fcn2_General(app)
            Fcn3_updateInfo(app)
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            delete(app)
            
        end

        % Image clicked function: imgShowHide
        function PanelVisibility_ImageClicked(app, event)
            
            if app.mainGrid.ColumnWidth{1} == 300
                app.imgShowHide.ImageSource = 'LT_HideRight.png';
                app.mainGrid.ColumnWidth{1} = 0;
            else
                app.imgShowHide.ImageSource = 'LT_HideLeft.png';
                app.mainGrid.ColumnWidth{1} = 300;
            end

        end

        % Image clicked function: Tab1_Image, Tab2_Image
        function PanelTab_SelectionChanged(app, event)
            
            switch event.Source
                case app.Tab1_Image; app.mainPanel_Grid.RowHeight([2,4]) = {'1x',0};
                case app.Tab2_Image; app.mainPanel_Grid.RowHeight([2,4]) = {0,'1x'};
            end

        end

        % Image clicked function: imgDensity, imgRoute, imgScatter
        function PlotMenu_SelectionChanged(app, event)
            
            d = uiprogressdlg(app.UIFigure, "Indeterminate", "on", "Message", "In progress...", "Interpreter", "html");

            switch event.Source
                case app.imgRoute
                    if app.hRoute_inROI.Visible
                        set([app.hRoute_outROI, app.hRoute_inROI], 'Visible', 0)
                        app.imgRoute.ImageSource = 'DriveTest_Route_OFF.png';
                    else
                        set([app.hRoute_outROI, app.hRoute_inROI], 'Visible', 1)
                        app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';
                    end

                case app.imgScatter
                    if app.hScatter.Visible
                        app.hScatter.Visible = 0;
                        app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                    else
                        app.hScatter.Visible = 1;
                        app.hDensity.Visible = 0;

                        app.imgScatter.ImageSource = 'DriveTest_Scatter_ON.png';
                        app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
                    end

                case app.imgDensity
                    if app.hDensity.Visible
                        app.hDensity.Visible = 0;
                        app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
                    else
                        app.hScatter.Visible = 0;
                        app.hDensity.Visible = 1;

                        app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
                        app.imgDensity.ImageSource = 'DriveTest_Density_ON.png';
                    end
            end
            drawnow

            delete(d)

        end

        % Value changed function: Emissions
        function Emissions_ValueChanged(app, event)

            Fcn2_General_AxesTitle(app)
            Fcn2_General_specTable(app)
            Fcn3_updateInfo(app)
            
        end

        % Selection changed function: FilterTree1
        function FilterTree1_SelectionChanged(app, event)
            
            app.FilterAction_add.Enable = 0;

            try
                if ~isempty(app.FilterTree1.SelectedNodes.NodeData)
                    app.FilterAction_add.Enable = 1;
                end
            catch
            end
            
        end

        % Selection changed function: FilterTree2
        function FilterTree2_SelectionChanged(app, event)
            
            if ~isempty(app.FilterTree2.SelectedNodes)
                idx = app.FilterTree2.SelectedNodes.NodeData;        
    
                for ii = 1:height(app.roiFilters)
                    if ii ~= idx
                        set(app.roiFilters.handle{ii}, 'InteractionsAllowed', 'none')
    
                    else
                        if app.roiFilters.type == "Level"
                            set(app.roiFilters.handle{ii}, 'InteractionsAllowed',  'translate')
                        else
                            set(app.roiFilters.handle{ii}, 'InteractionsAllowed',  'all')
                        end                    
                    end
                end
    
                Fcn7_Plot_StackOrder(app, app.roiFilters.handle{idx})
            end
            
        end

        % Image clicked function: FilterAction_add
        function FilterAction_addCallback(app, event)
            
            NodeData = app.FilterTree1.SelectedNodes.NodeData;
            
            app.FilterTree1.SelectedNodes = [];
            FilterTree1_SelectionChanged(app)

            set(app.FilterTree2, 'SelectedNodes', [], 'Enable', 0)
            set(app.FilterAction_del, 'Enable', 0)

            disableDefaultInteractivity(app.UIAxes1)
            switch NodeData
                case 1
                    Type = 'Level'; SubType = 'THRESHOLD';                        
                    THR  = min(app.filtTable.chPower);
                    hROI = images.roi.Line(app.UIAxes2, 'Position', [1 THR; height(app.filtTable) THR], 'Color', 'red', 'MarkerSize', 4, 'Deletable', 0, 'LineWidth', 1, 'InteractionsAllowed', 'translate', 'Tag', 'FilterLevel_THRESHOLD');                
                
                case 2
                    Type = 'Geographic ROI'; SubType = 'CIRCLE';
                    hROI = drawcircle(app.UIAxes1,    'Deletable', 0, 'Tag', 'ROI_Circle');
                
                case 3
                    Type = 'Geographic ROI'; SubType = 'RECTANGLE';
                    hROI = drawrectangle(app.UIAxes1, 'Deletable', 0, 'Tag', 'ROI_Rectangle', 'Rotatable', true);
                
                case 4
                    Type = 'Geographic ROI'; SubType = 'POLYGON';
                    hROI = drawpolygon(app.UIAxes1,   'Deletable', 0, 'Tag', 'ROI_Polygon');
            end
            enableDefaultInteractivity(app.UIAxes1)

            if isempty(hROI.Position)
                delete(hROI)
                
                app.FilterTree2.Enable = 1;
                if ~isempty(app.FilterTree2.Children)
                    app.FilterTree2.SelectedNodes = app.FilterTree2.Children(end);
                    FilterTree2_SelectionChanged(app)
                end

                return
            end

            addlistener(hROI, 'MovingROI', @app.filterROICallback);
            addlistener(hROI, 'ROIMoved',  @app.filterROICallback);

            app.roiFilters(end+1,:) = {Type, SubType, {hROI}, 1};
            Fcn2_General_TreeBuilding(app)
            Fcn3_updateInfo(app)

            if isvalid(hROI)
                Fcn7_Plot_StackOrder(app, hROI)
            end

        end

        % Image clicked function: FilterAction_del
        function FilterAction_delCallback(app, event)
            
            idx = app.FilterTree2.SelectedNodes.NodeData;

            app.FilterTree1.SelectedNodes = [];
            FilterTree1_SelectionChanged(app)
            
            delete(app.roiFilters.handle{idx}); 
            app.roiFilters(idx,:) = [];
            
            Fcn2_General_TreeBuilding(app)
            Fcn3_updateInfo(app)

            if ~isempty(app.roiFilters)
                Fcn7_Plot_StackOrder(app, app.roiFilters.handle{end})
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DriveTest';
            app.UIFigure.Icon = 'D:\MATLAB\Template\Icons\settings_LT.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.mainGrid = uigridlayout(app.UIFigure);
            app.mainPanel = uipanel(app.mainGrid);
            app.mainPanel_Grid = uigridlayout(app.mainPanel);
            app.Tab1_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab1_Grid.Tag = 'COLORLOCKED';
            app.Tab1_Title = uilabel(app.Tab1_Grid);
            app.Tab1_Title.Text = 'GENERAL';
            app.Tab1_Image = uiimage(app.Tab1_Grid);
            app.Tab1_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab1_Image.ImageSource = 'LT_settings.png';
            app.Tab1_Panel = uigridlayout(app.mainPanel_Grid);
            app.Emissions = uidropdown(app.Tab1_Panel);
            app.Emissions.Items = {};
            app.Emissions.ValueChangedFcn = createCallbackFcn(app, @Emissions_ValueChanged, true);
            app.Emissions.Value = {};
            app.rawDataInfo = uitextarea(app.Tab1_Panel);
            app.rawDataInfo.Editable = 'off';
            app.FilterTreeLabel = uilabel(app.Tab1_Panel);
            app.FilterTreeLabel.Text = 'Filters:';
            app.FilterTree1 = uitree(app.Tab1_Panel);
            app.FilterTree1.SelectionChangedFcn = createCallbackFcn(app, @FilterTree1_SelectionChanged, true);
            app.levelFilterNode = uitreenode(app.FilterTree1);
            app.levelFilterNode.Icon = 'LT_OCC.png';
            app.levelFilterNode.Text = 'LEVEL';
            app.levelFilter_thr = uitreenode(app.levelFilterNode);
            app.levelFilter_thr.NodeData = 1;
            app.levelFilter_thr.Text = 'THRESHOLD';
            app.geoFilterNode = uitreenode(app.FilterTree1);
            app.geoFilterNode.Icon = 'DriveTest_Scatter_OFF.png';
            app.geoFilterNode.Text = 'GEOGRAPHIC';
            app.geoFilter_Circle = uitreenode(app.geoFilterNode);
            app.geoFilter_Circle.NodeData = 2;
            app.geoFilter_Circle.Text = 'CIRCLE';
            app.geoFilter_Rectangle = uitreenode(app.geoFilterNode);
            app.geoFilter_Rectangle.NodeData = 3;
            app.geoFilter_Rectangle.Text = 'RECTANGLE';
            app.geoFilter_Polygon = uitreenode(app.geoFilterNode);
            app.geoFilter_Polygon.NodeData = 4;
            app.geoFilter_Polygon.Text = 'POLYGON';
            app.FilterTree2 = uitree(app.Tab1_Panel);
            app.FilterTree2.SelectionChangedFcn = createCallbackFcn(app, @FilterTree2_SelectionChanged, true);
            app.FilterAction_Grid1 = uigridlayout(app.Tab1_Panel);
            app.FilterAction_add = uiimage(app.FilterAction_Grid1);
            app.FilterAction_add.ImageClickedFcn = createCallbackFcn(app, @FilterAction_addCallback, true);
            app.FilterAction_add.Enable = 'off';
            app.FilterAction_add.ImageSource = 'LT_play.png';
            app.FilterAction_Grid2 = uigridlayout(app.Tab1_Panel);
            app.FilterAction_del = uiimage(app.FilterAction_Grid2);
            app.FilterAction_del.ImageClickedFcn = createCallbackFcn(app, @FilterAction_delCallback, true);
            app.FilterAction_del.Enable = 'off';
            app.FilterAction_del.ImageSource = 'LT_redX.png';
            app.FilterTreeInfo = uilabel(app.Tab1_Panel);
            app.FilterTreeInfo.Text = '100%';
            app.EmissionsLabel = uilabel(app.Tab1_Panel);
            app.EmissionsLabel.Text = 'Emission:';
            app.GridLayout = uigridlayout(app.Tab1_Panel);
            app.imgRoute = uiimage(app.GridLayout);
            app.imgRoute.ImageClickedFcn = createCallbackFcn(app, @PlotMenu_SelectionChanged, true);
            app.imgRoute.Tooltip = {'Route'};
            app.imgRoute.ImageSource = 'DriveTest_Route_ON.png';
            app.imgScatter = uiimage(app.GridLayout);
            app.imgScatter.ImageClickedFcn = createCallbackFcn(app, @PlotMenu_SelectionChanged, true);
            app.imgScatter.Tooltip = {'Distortion'};
            app.imgScatter.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.imgDensity = uiimage(app.GridLayout);
            app.imgDensity.ImageClickedFcn = createCallbackFcn(app, @PlotMenu_SelectionChanged, true);
            app.imgDensity.Tooltip = {'Density'};
            app.imgDensity.ImageSource = 'DriveTest_Density_OFF.png';
            app.Tab2_Grid = uigridlayout(app.mainPanel_Grid);
            app.Tab2_Grid.Tag = 'COLORLOCKED';
            app.Tab2_Title = uilabel(app.Tab2_Grid);
            app.Tab2_Title.Text = 'FILTERED DATA';
            app.Tab2_Image = uiimage(app.Tab2_Grid);
            app.Tab2_Image.ImageClickedFcn = createCallbackFcn(app, @PanelTab_SelectionChanged, true);
            app.Tab2_Image.ImageSource = 'LT_Detection.png';
            app.Tab2_Panel = uigridlayout(app.mainPanel_Grid);
            app.binTable_Grid = uigridlayout(app.Tab2_Panel);
            app.binTable_UITable = uitable(app.binTable_Grid);
            app.binTable_UITable.ColumnName = {''; ''; ''; ''};
            app.binTable_UITable.RowName = {};
            app.binTable_UITable.SelectionType = 'row';
            app.binTable_Col1 = uilabel(app.binTable_Grid);
            app.binTable_Col1.Text = {'  ID'; '  '};
            app.binTable_Col2 = uilabel(app.binTable_Grid);
            app.binTable_Col2.Text = {'  Latitude'; ' '};
            app.binTable_Col3 = uilabel(app.binTable_Grid);
            app.binTable_Col3.Text = {'  Longitude'; '  '};
            app.binTable_Col4 = uilabel(app.binTable_Grid);
            app.binTable_Col4.Text = {'  Channel power'; '  (dBm)'};
            app.imgShowHide = uiimage(app.mainGrid);
            app.imgShowHide.ImageClickedFcn = createCallbackFcn(app, @PanelVisibility_ImageClicked, true);
            app.imgShowHide.ImageSource = 'LT_HideLeft.png';
            app.Panel = uipanel(app.mainGrid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winDriveTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end