classdef winMain < matlab.apps.AppBase
    %WINMAIN Summary...
    %   Help...

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Grid1            matlab.ui.container.GridLayout
        toolbar          matlab.ui.container.Panel
        tool_Grid1       matlab.ui.container.GridLayout
        tool_Grid2       matlab.ui.container.GridLayout
        tool_Button3     matlab.ui.control.Button
        tool_Button2     matlab.ui.control.Button
        tool_Button1     matlab.ui.control.Button
        menu             matlab.ui.container.Panel
        menu_Grid1       matlab.ui.container.GridLayout
        menu_Grid2       matlab.ui.container.GridLayout
        menu_Button5     matlab.ui.control.StateButton
        menu_Button4     matlab.ui.control.StateButton
        menu_Button3     matlab.ui.control.StateButton
        menu_Separator2  matlab.ui.control.Image
        menu_Button2     matlab.ui.control.StateButton
        menu_Separator1  matlab.ui.control.Image
        menu_Button1     matlab.ui.control.StateButton
        menu_Grid3       matlab.ui.container.GridLayout
        menu_pathImg     matlab.ui.control.Image
        menu_path        matlab.ui.control.DropDown
        tabGroup         matlab.ui.container.TabGroup
        tab1             matlab.ui.container.Tab
        tab1_Grid        matlab.ui.container.GridLayout
        home_label5      matlab.ui.control.Label
        home_label4      matlab.ui.control.Label
        home_label3      matlab.ui.control.Label
        home_label2      matlab.ui.control.Label
        home_label1      matlab.ui.control.Label
        home_img5        matlab.ui.control.Button
        home_img4        matlab.ui.control.Button
        home_img3        matlab.ui.control.Button
        home_img2        matlab.ui.control.Button
        home_img1        matlab.ui.control.Button
        home_appVersion  matlab.ui.control.Label
        tab2             matlab.ui.container.Tab
        tab2_Grid        matlab.ui.container.GridLayout
        tab3_Label_2     matlab.ui.control.Label
        tab2_Title       matlab.ui.control.Label
        tab3             matlab.ui.container.Tab
        tab3_Grid        matlab.ui.container.GridLayout
        tab3_Title       matlab.ui.control.Label
        tab3_Label       matlab.ui.control.Label
        tab4             matlab.ui.container.Tab
        tab4_Grid        matlab.ui.container.GridLayout
        tab5_Label_2     matlab.ui.control.Label
        tab4_Title       matlab.ui.control.Label
        tab5             matlab.ui.container.Tab
        tab5_Grid        matlab.ui.container.GridLayout
        tab5_Title       matlab.ui.control.Label
        tab5_Label       matlab.ui.control.Label
    end

    
    properties (Access = public)

        Theme = 'DarkCyan';

    end    
    
    methods (Access = public)


        function startup_appUserPath(app)

            userpaths = {fullfile(getenv('USERPROFILE'), 'Documents'); ...
                         fullfile(getenv('USERPROFILE'), 'Downloads')};

            set(app.menu_path, 'Items', userpaths, 'Value', userpaths{end})
        
        end


        function ModeOperation(app)
            
            if     app.menu_Button1.Value; idx = 1;
            elseif app.menu_Button2.Value; idx = 2;
            elseif app.menu_Button3.Value; idx = 3;            
            elseif app.menu_Button4.Value; idx = 4;
            elseif app.menu_Button5.Value; idx = 5;
            end

            app.tabGroup.SelectedTab = app.tabGroup.Children(idx);
            drawnow
            
        end


        function MessageBox(app, type, msg)

            msg = sprintf('<font style="font-size:12;">%s</font>', msg);
            switch type
                case 'error';   uialert(app.UIFigure, msg, 'Main', 'Interpreter', 'html');
                case 'warning'; uialert(app.UIFigure, msg, 'Main', 'Interpreter', 'html', 'Icon', 'warning');
                case 'info';    uialert(app.UIFigure, msg, 'Main', 'Interpreter', 'html', 'Icon', 'info_LT.png')
                case 'startup'; uialert(app.UIFigure, msg, 'Main', 'Interpreter', 'html', 'CloseFcn', @(~,~)closeRequest(app));
            end

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            
            mainMonitor = get(0, 'MonitorPositions');

            [~, idx]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(idx,:);
                                  
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            startup_appUserPath(app)
            
        end

        % Close request function: UIFigure
        function closeRequest(app, event)

            delete(app)
            
        end

        % Value changed function: menu_Button1, menu_Button2, 
        % menu_Button3, menu_Button4, menu_Button5
        function Menu_SelectionChanged(app, event)
            
            if event.Source.Value
                set(findobj(app.menu_Grid2, 'Type', 'uistatebutton', '-not', 'Tag', event.Source.Tag), 'Value', 0)

            else
                event.Source.Value = 1;
                return
            end

            ModeOperation(app)
            
        end

        % Button pushed function: home_img1, home_img2, home_img3, 
        % home_img4, home_img5
        function Menu_QuickAccess(app, event)
            
            switch event.Source
                case app.home_img1; app.menu_Button2.Value = 1; Menu_SelectionChanged(app, struct('Source', app.menu_Button2))
                case app.home_img2; app.menu_Button3.Value = 1; Menu_SelectionChanged(app, struct('Source', app.menu_Button3))
                case app.home_img3; app.menu_Button4.Value = 1; Menu_SelectionChanged(app, struct('Source', app.menu_Button4))
                case app.home_img4; app.menu_Button5.Value = 1; Menu_SelectionChanged(app, struct('Source', app.menu_Button5))
                case app.home_img5; tool_Button1Pushed(app)
            end

        end

        % Button pushed function: tool_Button1
        function tool_Button1Pushed(app, event)
            
            d = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', 'Interpreter', 'html');
            d.Message = '<font style="font-size:12;">In progress...</font>';
            
            winCredential(app);
            drawnow

            delete(d)
            
        end

        % Button pushed function: tool_Button2
        function tool_Button2Pushed(app, event)
            
            % Theme colors
            LT_BackgroundColor_Menu = [0.90,0.90,0.90];
            DT_BackgroundColor_Menu = [0.38,0.60,0.73];

            % Button objects
            h = findall(groot, {{'Parent', app.menu_Grid2}, '-and', {'Type', 'uibutton', '-or', 'Type', 'uistatebutton'}}, '-or', ...
                               {{'Parent', app.tool_Grid2}, '-and', {'Type', 'uibutton', '-or', 'Type', 'uistatebutton'}});

            if app.Theme == "light"
                app.menu_Grid2.BackgroundColor = DT_BackgroundColor_Menu;
                set(app.menu_Grid2.Children, 'BackgroundColor', DT_BackgroundColor_Menu)
                app.tool_Grid2.BackgroundColor = DT_BackgroundColor_Menu;

                set(h, 'BackgroundColor', DT_BackgroundColor_Menu)
                app.menu_Button1.Icon = "home_DT.png";
                app.menu_Button2.Icon = "OpenFile_DT.png";
                app.menu_Button3.Icon = "playback_DT.png";
                app.menu_Button4.Icon = "report_DT.png";
                app.menu_Button5.Icon = "redmine_DT.png";

                app.tool_Button1.Icon = "settings_DT.png";
                app.tool_Button2.Icon = "theme_DT.png";
                app.tool_Button3.Icon = "info_DT.png";

                app.menu_Separator1.ImageSource = "Line_DT.png";
                app.menu_Separator2.ImageSource = "Line_DT.png";                
                app.menu_pathImg.ImageSource = "zoom_DT.png";

                app.Theme = "DarkCyan";
            else
                app.menu_Grid2.BackgroundColor = LT_BackgroundColor_Menu;
                set(app.menu_Grid2.Children, 'BackgroundColor', LT_BackgroundColor_Menu)
                app.tool_Grid2.BackgroundColor = LT_BackgroundColor_Menu;

                set(h, 'BackgroundColor', LT_BackgroundColor_Menu)
                app.menu_Button1.Icon = "home_LT.png";
                app.menu_Button2.Icon = "OpenFile_LT.png";
                app.menu_Button3.Icon = "playback_LT.png";
                app.menu_Button4.Icon = "report_LT.png";
                app.menu_Button5.Icon = "redmine_LT.png";

                app.tool_Button1.Icon = "settings_LT.png";
                app.tool_Button2.Icon = "theme_LT.png";
                app.tool_Button3.Icon = "info_LT.png";

                app.menu_Separator1.ImageSource = "Line_LT.png";
                app.menu_Separator2.ImageSource = "Line_LT.png";
                app.menu_pathImg.ImageSource    = "zoom_LT.png";

                app.Theme = "light";
            end

        end

        % Button pushed function: tool_Button3
        function tool_Button3Pushed(app, event)
            
            if     app.menu_Button1.Value; Tag = 'TAB1';
            elseif app.menu_Button2.Value; Tag = 'TAB2';
            elseif app.menu_Button3.Value; Tag = 'TAB3';
            elseif app.menu_Button4.Value; Tag = 'TAB4';
            elseif app.menu_Button5.Value; Tag = 'TAB5';
            end

            msg = sprintf(['<b style="color: #262626; font-size: 12;">%s</b>\n' ...
                       '<font style="color: #262626; font-size:11;">Est ullamcorper eget nulla facilisi. <b>Sollicitudin</b> nibh sit amet commodo nulla facilisi. Varius morbi enim nunc faucibus a pellentesque sit. Iaculis eu non diam phasellus vestibulum lorem sed. Tortor at auctor urna nunc id cursus metus aliquam. Tristique et egestas quis ipsum suspendisse ultrices. In nibh mauris cursus mattis molestie a iaculis. At imperdiet dui accumsan sit amet nulla. Sed arcu non odio euismod lacinia at quis risus. Egestas dui id ornare arcu odio ut sem. Quisque egestas diam in arcu cursus. Blandit cursus risus at ultrices mi tempus imperdiet nulla malesuada. Sed cras ornare arcu dui vivamus arcu felis bibendum ut. Sit amet est placerat in egestas erat imperdiet sed euismod. Congue quisque egestas diam in arcu cursus euismod quis viverra. A pellentesque sit amet porttitor eget dolor morbi non arcu. Condimentum vitae sapien pellentesque habitant morbi tristique senectus et netus. Consectetur libero id faucibus nisl tincidunt eget nullam. Tincidunt arcu non sodales neque sodales ut etiam sit amet. Nisi porta lorem mollis aliquam ut porttitor.</font>'], Tag);
            
            MessageBox(app, 'info', msg)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Main';
            app.UIFigure.Icon = 'home_Puzzle.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeRequest, true);
            app.Grid1 = uigridlayout(app.UIFigure);
            app.Grid1.Tooltip = {''};
            app.tabGroup = uitabgroup(app.Grid1);
            app.tab1 = uitab(app.tabGroup);
            app.tab1_Grid = uigridlayout(app.tab1);
            app.home_appVersion = uilabel(app.tab1_Grid);
            app.home_appVersion.Interpreter = 'html';
            app.home_appVersion.Text = {'<b style="font-size: 11;">Main </b>'; '<font>v. 1.00 </font>'};
            app.home_img1 = uibutton(app.tab1_Grid, 'push');
            app.home_img1.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_img1.Tag = 'Mosaic1';
            app.home_img1.Icon = 'OpenFile_LT_LR.png';
            app.home_img1.Text = {'Feature 1'; 'Open File'};
            app.home_img2 = uibutton(app.tab1_Grid, 'push');
            app.home_img2.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_img2.Tag = 'Mosaic2';
            app.home_img2.Icon = 'playback_LT_LR.png';
            app.home_img2.Text = {'Feature 2'; 'Data playback'};
            app.home_img3 = uibutton(app.tab1_Grid, 'push');
            app.home_img3.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_img3.Tag = 'Mosaic3';
            app.home_img3.Icon = 'report_LT_LR.png';
            app.home_img3.Text = {'Feature 3'; 'Report generator'};
            app.home_img4 = uibutton(app.tab1_Grid, 'push');
            app.home_img4.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_img4.Tag = 'Mosaic4';
            app.home_img4.Icon = 'redmine_LT_LR.png';
            app.home_img4.Text = {'Feature 4'; 'API Redmine'};
            app.home_img5 = uibutton(app.tab1_Grid, 'push');
            app.home_img5.ButtonPushedFcn = createCallbackFcn(app, @Menu_QuickAccess, true);
            app.home_img5.Tag = 'Mosaic5';
            app.home_img5.Icon = 'settings_LT_LR.png';
            app.home_img5.Text = {'Feature 5'; 'General Settings'};
            app.home_label1 = uilabel(app.tab1_Grid);
            app.home_label1.Interpreter = 'html';
            app.home_label1.Text = {'Lorem ipsum dolor sit amet, <i>consectetur</i> adipiscing elit, sed do  '; 'eiusmod tempor incididunt ut labore et dolore magna aliqua  '};
            app.home_label2 = uilabel(app.tab1_Grid);
            app.home_label2.Interpreter = 'html';
            app.home_label2.Text = {'Lorem ipsum dolor sit  '; 'sed do eiusmod tempor  '};
            app.home_label3 = uilabel(app.tab1_Grid);
            app.home_label3.Interpreter = 'html';
            app.home_label3.Text = {'Lorem ipsum dolor sit  '; 'sed do eiusmod tempor  '};
            app.home_label4 = uilabel(app.tab1_Grid);
            app.home_label4.Text = {'Lorem ipsum dolor sit amet, consectetur adipiscing elit, sed do  '; 'eiusmod tempor incididunt ut labore et dolore magna aliqua  '};
            app.home_label5 = uilabel(app.tab1_Grid);
            app.home_label5.Interpreter = 'html';
            app.home_label5.Text = {'Lorem ipsum dolor sit  '; 'sed do eiusmod tempor  '};
            app.tab2 = uitab(app.tabGroup);
            app.tab2_Grid = uigridlayout(app.tab2);
            app.tab2_Title = uilabel(app.tab2_Grid);
            app.tab2_Title.Text = 'OPEN FILE';
            app.tab3_Label_2 = uilabel(app.tab2_Grid);
            app.tab3_Label_2.Interpreter = 'html';
            app.tab3_Label_2.Text = {'<b style="color: #6199ba; font-size: 40;">TAB2</b>'; '<font>Tab description...</font>'};
            app.tab3 = uitab(app.tabGroup);
            app.tab3_Grid = uigridlayout(app.tab3);
            app.tab3_Label = uilabel(app.tab3_Grid);
            app.tab3_Label.Interpreter = 'html';
            app.tab3_Label.Text = {'<b style="color: #6199ba; font-size: 40;">TAB3</b>'; '<font>Tab description...</font>'};
            app.tab3_Title = uilabel(app.tab3_Grid);
            app.tab3_Title.Text = 'DATA PLAYBACK';
            app.tab4 = uitab(app.tabGroup);
            app.tab4_Grid = uigridlayout(app.tab4);
            app.tab4_Title = uilabel(app.tab4_Grid);
            app.tab4_Title.Text = 'REPORT GENERATOR';
            app.tab5_Label_2 = uilabel(app.tab4_Grid);
            app.tab5_Label_2.Interpreter = 'html';
            app.tab5_Label_2.Text = {'<b style="color: #6199ba; font-size: 40;">TAB4</b>'; '<font>Tab description...</font>'};
            app.tab5 = uitab(app.tabGroup);
            app.tab5_Grid = uigridlayout(app.tab5);
            app.tab5_Label = uilabel(app.tab5_Grid);
            app.tab5_Label.Interpreter = 'html';
            app.tab5_Label.Text = {'<b style="color: #6199ba; font-size: 40;">TAB5</b>'; '<font>Tab description...</font>'};
            app.tab5_Title = uilabel(app.tab5_Grid);
            app.tab5_Title.Text = 'API REDMINE';
            app.menu = uipanel(app.Grid1);
            app.menu_Grid1 = uigridlayout(app.menu);
            app.menu_Grid2 = uigridlayout(app.menu_Grid1);
            app.menu_Grid3 = uigridlayout(app.menu_Grid2);
            app.menu_path = uidropdown(app.menu_Grid3);
            app.menu_path.Items = {'Path'};
            app.menu_path.Value = 'Path';
            app.menu_pathImg = uiimage(app.menu_Grid3);
            app.menu_pathImg.ImageSource = 'zoom_DT.png';
            app.menu_Button1 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button1.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.menu_Button1.Tag = 'TAB1';
            app.menu_Button1.Tooltip = {''};
            app.menu_Button1.Icon = 'home_DT.png';
            app.menu_Button1.Text = '';
            app.menu_Button1.Value = true;
            app.menu_Separator1 = uiimage(app.menu_Grid2);
            app.menu_Separator1.Enable = 'off';
            app.menu_Separator1.ImageSource = 'Line_DT.png';
            app.menu_Button2 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button2.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.menu_Button2.Tag = 'TAB2';
            app.menu_Button2.Tooltip = {''};
            app.menu_Button2.Icon = 'OpenFile_DT.png';
            app.menu_Button2.Text = '';
            app.menu_Separator2 = uiimage(app.menu_Grid2);
            app.menu_Separator2.Enable = 'off';
            app.menu_Separator2.ImageSource = 'Line_DT.png';
            app.menu_Button3 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button3.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.menu_Button3.Tag = 'TAB3';
            app.menu_Button3.Tooltip = {''};
            app.menu_Button3.Icon = 'playback_DT.png';
            app.menu_Button3.Text = '';
            app.menu_Button4 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button4.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.menu_Button4.Tag = 'TAB4';
            app.menu_Button4.Tooltip = {''};
            app.menu_Button4.Icon = 'report_DT.png';
            app.menu_Button4.Text = '';
            app.menu_Button5 = uibutton(app.menu_Grid2, 'state');
            app.menu_Button5.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.menu_Button5.Tag = 'TAB5';
            app.menu_Button5.Tooltip = {''};
            app.menu_Button5.Icon = 'redmine_DT.png';
            app.menu_Button5.Text = '';
            app.toolbar = uipanel(app.Grid1);
            app.tool_Grid1 = uigridlayout(app.toolbar);
            app.tool_Grid2 = uigridlayout(app.tool_Grid1);
            app.tool_Button1 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button1.ButtonPushedFcn = createCallbackFcn(app, @tool_Button1Pushed, true);
            app.tool_Button1.Icon = 'settings_DT.png';
            app.tool_Button1.Tooltip = {''};
            app.tool_Button1.Text = '';
            app.tool_Button2 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button2.ButtonPushedFcn = createCallbackFcn(app, @tool_Button2Pushed, true);
            app.tool_Button2.Icon = 'theme_DT.png';
            app.tool_Button2.Tooltip = {''};
            app.tool_Button2.Text = '';
            app.tool_Button3 = uibutton(app.tool_Grid2, 'push');
            app.tool_Button3.ButtonPushedFcn = createCallbackFcn(app, @tool_Button3Pushed, true);
            app.tool_Button3.Icon = 'info_DT.png';
            app.tool_Button3.Tooltip = {''};
            app.tool_Button3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end