classdef winQlik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        Table              matlab.ui.container.GridLayout
        Column10           matlab.ui.control.Label
        Column9            matlab.ui.control.Label
        Column8            matlab.ui.control.Label
        Column7            matlab.ui.control.Label
        Column6            matlab.ui.control.Label
        Column4            matlab.ui.control.Label
        Column3            matlab.ui.control.Label
        Column2            matlab.ui.control.Label
        Column1            matlab.ui.control.Label
        UITable            matlab.ui.control.Table
        Description        matlab.ui.container.Panel
        Description_Grid   matlab.ui.container.GridLayout
        Description_notes  matlab.ui.control.Label
        Description_img    matlab.ui.control.Image
        Menu               matlab.ui.container.Panel
        Menu_Grid1         matlab.ui.container.GridLayout
        Menu_Grid2         matlab.ui.container.GridLayout
        Menu_button5       matlab.ui.control.StateButton
        Menu_button4       matlab.ui.control.StateButton
        Menu_button3       matlab.ui.control.StateButton
        Menu_button2       matlab.ui.control.StateButton
        Menu_button1       matlab.ui.control.StateButton
        Menu_img           matlab.ui.control.Image
        Menu_title         matlab.ui.control.Label
        TabGroup           matlab.ui.container.TabGroup
        Tab1               matlab.ui.container.Tab
        Tab1_Grid          matlab.ui.container.GridLayout
        Tab1_Panel         matlab.ui.container.Panel
        Tab2               matlab.ui.container.Tab
        Tab2_Grid          matlab.ui.container.GridLayout
        Tab2_Panel         matlab.ui.container.Panel
        Tab3               matlab.ui.container.Tab
        Tab3_Grid          matlab.ui.container.GridLayout
        Tab3_Label         matlab.ui.control.Label
        Tab4               matlab.ui.container.Tab
        Tab4_Grid          matlab.ui.container.GridLayout
        Tab4_Label         matlab.ui.control.Label
        Tab5               matlab.ui.container.Tab
        Tab5_Grid          matlab.ui.container.GridLayout
        Tab5_Label         matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        UIAxes1
        UIAxes2
        UIAxes3
        UIAxes4

    end
    
    methods (Access = private)
        
        function ModeOperation(app)
            
            if     app.Menu_button1.Value; idx = 1;
            elseif app.Menu_button2.Value; idx = 2;
            elseif app.Menu_button3.Value; idx = 3;
            elseif app.Menu_button4.Value; idx = 4;
            elseif app.Menu_button5.Value; idx = 5;
            end

            app.TabGroup.SelectedTab = app.TabGroup.Children(idx);
            drawnow
            
        end

        function Fcn1_Tab1_AxesCreation(app)

            t = tiledlayout(app.Tab1_Panel, 1, 1, "Padding", "tight", "TileSpacing", "tight");

            app.UIAxes1 = geoaxes(t, 'FontSize', 6, 'Units', 'pixels', 'Basemap', 'darkwater');
            app.UIAxes1.Layout.Tile = 1;

            while true
                try
                    app.UIAxes1.LatitudeLabel.String  = '';
                    app.UIAxes1.LongitudeLabel.String = '';

                    hold(app.UIAxes1, 'on')

                    break
                catch
                    pause(.1)
                end
            end

            try
                geobasemap(app.UIAxes1, 'streets-light')
            catch
            end

        end

        function Fcn1_Tab2_AxesCreation(app)

            t = tiledlayout(app.Tab2_Panel, 2, 2, "Padding", "tight", "TileSpacing", "compact");

            % app.UIAxes2
            app.UIAxes2 = uiaxes(t);
            app.UIAxes2.Layout.Tile = 1;

            app.UIAxes2.Color = [0.94, .94, .94];
            app.UIAxes2.FontSize = 8;


            % app.UIAxes3
            app.UIAxes3 = uiaxes(t);
            app.UIAxes3.Layout.Tile = 2;

            app.UIAxes3.Color = [0.94, .94, .94];
            app.UIAxes3.FontSize = 8;


            % app.UIAxes4
            app.UIAxes4 = uiaxes(t);
            app.UIAxes4.Layout.Tile = 3;
            app.UIAxes4.Layout.TileSpan = [1 2];

            app.UIAxes4.Color = [0.94, .94, .94];
            app.UIAxes4.FontSize = 8;

        end
        
        function Fcn2_anateldb(app)

            load('QlikDB.mat', 'AnatelDB', '-mat')

            AnatelDB.Frequency = double(AnatelDB.Frequency);
            AnatelDB = movevars(AnatelDB, 'Description', 'After',  'BW');

            app.UITable.Data = AnatelDB;
            app.UITable.Selection = [];
            
        end

        function Fcn3_Plot(app)

            % app.UIAxes1
            cla(app.UIAxes1)
            hold(app.UIAxes1, "on")    

            geoscatter(app.UIAxes1, app.UITable.Data.Latitude, app.UITable.Data.Longitude);
            geolimits(app.UIAxes1, [-34, 4], [-93,-15])

            hold(app.UIAxes1, "off")

            
            % app.UIAxes2 
            [X,Y] = meshgrid(1:0.5:10,1:20);
            Z = sin(X) + cos(Y);
            surf(app.UIAxes2, X,Y,Z)
            
            
            % app.UIAxes3
            ezpolar(app.UIAxes3, @(t) sin (5/4 * t));


            % app.UIAxes4
            histogram(app.UIAxes4, randn(10001,1), "BinWidth", .01)

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            mainMonitor = get(0, 'MonitorPositions');

            [~, ind]    = max(mainMonitor(:,3));
            mainMonitor = mainMonitor(ind,:);
            
            app.UIFigure.Position(1:2) = [mainMonitor(1)+round((mainMonitor(3)-app.UIFigure.Position(3))/2), ...
                                          mainMonitor(2)+round((mainMonitor(4)-app.UIFigure.Position(4)-30)/2)];

            Menu_imgClicked(app)

            Fcn1_Tab1_AxesCreation(app)
            Fcn1_Tab2_AxesCreation(app)
            Fcn2_anateldb(app)
            Fcn3_Plot(app)
            
        end

        % Image clicked function: Menu_img
        function Menu_imgClicked(app, event)
            
            if app.GridLayout.RowHeight{2} == 132
                app.GridLayout.RowHeight{2} = 0;
                app.Tab1_Grid.Padding(4)  = 30;
                app.Tab2_Grid.Padding(4)  = 30;
            else
                app.GridLayout.RowHeight{2} = 132;
                app.Tab1_Grid.Padding(4)  = 162;
                app.Tab2_Grid.Padding(4)  = 162;
            end

        end

        % Value changed function: Menu_button1, Menu_button2, 
        % Menu_button3, Menu_button4, Menu_button5
        function Menu_SelectionChanged(app, event)

            if event.Source.Value
                h = findall(groot, 'Parent', app.Menu_Grid2, 'Type', 'uistatebutton', '-not', 'Tag', event.Source.Tag);
                
                for ii = 1:numel(h)
                    if h(ii).Value
                        set(h(ii), 'Value', 0, 'Icon', sprintf('LT_%s.png', h(ii).Tag), 'BackgroundColor', '#FFFFFF')
                        break
                    end
                end

                set(event.Source, 'Icon', sprintf('DT_%s.png', event.Source.Tag), 'BackgroundColor', "#808080")

            else
                event.Source.Value = 1;
                return
            end

            ModeOperation(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Qlik';
            app.UIFigure.Icon = 'D:\MATLAB\Template\Icons\DriveTest_Scatter_OFF.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1';
            app.Tab1_Grid = uigridlayout(app.Tab1);
            app.Tab1_Panel = uipanel(app.Tab1_Grid);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2_Grid = uigridlayout(app.Tab2);
            app.Tab2_Panel = uipanel(app.Tab2_Grid);
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.Tab3_Grid = uigridlayout(app.Tab3);
            app.Tab3_Label = uilabel(app.Tab3_Grid);
            app.Tab3_Label.Text = 'TAB 3';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.Tab4_Grid = uigridlayout(app.Tab4);
            app.Tab4_Label = uilabel(app.Tab4_Grid);
            app.Tab4_Label.Text = 'TAB 4';
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.Tab5_Grid = uigridlayout(app.Tab5);
            app.Tab5_Label = uilabel(app.Tab5_Grid);
            app.Tab5_Label.Text = 'TAB 5';
            app.Menu = uipanel(app.GridLayout);
            app.Menu_Grid1 = uigridlayout(app.Menu);
            app.Menu_title = uilabel(app.Menu_Grid1);
            app.Menu_title.Interpreter = 'html';
            app.Menu_title.Text = {'Qlik'; '<font style="color: #808080; font-size: 9;">GENERAL INFORMATION</font>'};
            app.Menu_img = uiimage(app.Menu_Grid1);
            app.Menu_img.ImageClickedFcn = createCallbackFcn(app, @Menu_imgClicked, true);
            app.Menu_img.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.Menu_Grid2 = uigridlayout(app.Menu_Grid1);
            app.Menu_button1 = uibutton(app.Menu_Grid2, 'state');
            app.Menu_button1.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.Menu_button1.Tag = 'HOME';
            app.Menu_button1.Icon = 'DT_home.png';
            app.Menu_button1.Text = '';
            app.Menu_button1.Value = true;
            app.Menu_button2 = uibutton(app.Menu_Grid2, 'state');
            app.Menu_button2.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.Menu_button2.Tag = 'PLAYBACK';
            app.Menu_button2.Icon = 'LT_playback.png';
            app.Menu_button2.Text = '';
            app.Menu_button3 = uibutton(app.Menu_Grid2, 'state');
            app.Menu_button3.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.Menu_button3.Tag = 'CONNECT';
            app.Menu_button3.Icon = 'LT_connect.png';
            app.Menu_button3.Text = '';
            app.Menu_button4 = uibutton(app.Menu_Grid2, 'state');
            app.Menu_button4.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.Menu_button4.Tag = 'REPORT';
            app.Menu_button4.Icon = 'LT_report.png';
            app.Menu_button4.Text = '';
            app.Menu_button5 = uibutton(app.Menu_Grid2, 'state');
            app.Menu_button5.ValueChangedFcn = createCallbackFcn(app, @Menu_SelectionChanged, true);
            app.Menu_button5.Tag = 'SETTINGS';
            app.Menu_button5.Icon = 'LT_settings.png';
            app.Menu_button5.Text = '';
            app.Description = uipanel(app.GridLayout);
            app.Description_Grid = uigridlayout(app.Description);
            app.Description_img = uiimage(app.Description_Grid);
            app.Description_img.ImageSource = 'DriveTest_Scatter_OFF.png';
            app.Description_notes = uilabel(app.Description_Grid);
            app.Description_notes.Interpreter = 'html';
            app.Description_notes.Text = {'Qlik'; '<font style="font-size:9";>GENERAL INFORMATION</font>'; ''; '<i style="font-size: 10";>Published on 02/22/2023'; 'Updated on 02/22/2023'; 'Created by Eric Magalhães Delgado</i>'};
            app.Table = uigridlayout(app.GridLayout);
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true false false false false false false false false];
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.Column1 = uilabel(app.Table);
            app.Column1.Text = {'  ID'; '  '};
            app.Column2 = uilabel(app.Table);
            app.Column2.Text = {'  Frequency'; '  (MHz)'};
            app.Column3 = uilabel(app.Table);
            app.Column3.Text = {'   Latitude'; '  '};
            app.Column4 = uilabel(app.Table);
            app.Column4.Text = {'  Longitude'; '  '};
            app.Column6 = uilabel(app.Table);
            app.Column6.Text = {'  Service'; '  '};
            app.Column7 = uilabel(app.Table);
            app.Column7.Text = {'  Station'; '  '};
            app.Column8 = uilabel(app.Table);
            app.Column8.Text = {'  Designation'; '  class'};
            app.Column9 = uilabel(app.Table);
            app.Column9.Text = {'  Bandwidth'; '  (kHz)'};
            app.Column10 = uilabel(app.Table);
            app.Column10.Text = {'  Description'; '  '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winQlik

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end